classdef INTERFAZ_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InterfazprincipalTab         matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel  matlab.ui.control.Label
        PROCESAMIENTOAVANZADODESEALESEIMGENESTRABAJOPARCIALLabel  matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        GrabacionEmpezo              matlab.ui.control.Label
        LapalabraesEditField         matlab.ui.control.EditField
        LapalabraesEditFieldLabel    matlab.ui.control.Label
        VerArbolButton               matlab.ui.control.Button
        ReproducirButton             matlab.ui.control.Button
        GrabarButton                 matlab.ui.control.Button
        TcsegundosEditField          matlab.ui.control.EditField
        TcsegundosEditFieldLabel     matlab.ui.control.Label
        decanalesEditField           matlab.ui.control.EditField
        decanalesEditFieldLabel      matlab.ui.control.Label
        rbitsmuestrasEditField       matlab.ui.control.EditField
        rbitsmuestrasEditFieldLabel  matlab.ui.control.Label
        FrecuenciaFsEditField        matlab.ui.control.EditField
        FrecuenciaFsEditFieldLabel   matlab.ui.control.Label
        GraficaFrecuencia            matlab.ui.control.UIAxes
        GraficaTiempo                matlab.ui.control.UIAxes
        ArboldedescomposicionTab     matlab.ui.container.Tab
        E57EditField                 matlab.ui.control.NumericEditField
        E57EditFieldLabel            matlab.ui.control.Label
        E56EditField                 matlab.ui.control.NumericEditField
        E56EditFieldLabel            matlab.ui.control.Label
        E55EditField                 matlab.ui.control.NumericEditField
        E55EditFieldLabel            matlab.ui.control.Label
        E54EditField                 matlab.ui.control.NumericEditField
        E54EditFieldLabel            matlab.ui.control.Label
        E53EditField                 matlab.ui.control.NumericEditField
        E53EditFieldLabel            matlab.ui.control.Label
        E52EditField                 matlab.ui.control.NumericEditField
        E52EditFieldLabel            matlab.ui.control.Label
        E51EditField                 matlab.ui.control.NumericEditField
        E51EditFieldLabel            matlab.ui.control.Label
        E50EditField                 matlab.ui.control.NumericEditField
        E50EditFieldLabel            matlab.ui.control.Label
        E49EditField                 matlab.ui.control.NumericEditField
        E49EditFieldLabel            matlab.ui.control.Label
        E48EditField                 matlab.ui.control.NumericEditField
        E48EditFieldLabel            matlab.ui.control.Label
        E47EditField                 matlab.ui.control.NumericEditField
        E47EditFieldLabel            matlab.ui.control.Label
        E46EditField                 matlab.ui.control.NumericEditField
        E46EditFieldLabel            matlab.ui.control.Label
        E45EditField                 matlab.ui.control.NumericEditField
        E45EditFieldLabel            matlab.ui.control.Label
        E44EditField                 matlab.ui.control.NumericEditField
        E44EditFieldLabel            matlab.ui.control.Label
        E43EditField                 matlab.ui.control.NumericEditField
        E43Label                     matlab.ui.control.Label
        E42EditField                 matlab.ui.control.NumericEditField
        E42EditFieldLabel            matlab.ui.control.Label
        E41EditField                 matlab.ui.control.NumericEditField
        E41EditFieldLabel            matlab.ui.control.Label
        E40EditField                 matlab.ui.control.NumericEditField
        E40EditFieldLabel            matlab.ui.control.Label
        E39EditField                 matlab.ui.control.NumericEditField
        E39EditFieldLabel            matlab.ui.control.Label
        E38EditField                 matlab.ui.control.NumericEditField
        E38EditFieldLabel            matlab.ui.control.Label
        E37EditField                 matlab.ui.control.NumericEditField
        E37EditFieldLabel            matlab.ui.control.Label
        E36EditField                 matlab.ui.control.NumericEditField
        E36EditFieldLabel            matlab.ui.control.Label
        E35EditField                 matlab.ui.control.NumericEditField
        E35EditFieldLabel            matlab.ui.control.Label
        E34EditField                 matlab.ui.control.NumericEditField
        E34EditFieldLabel            matlab.ui.control.Label
        E33EditField                 matlab.ui.control.NumericEditField
        E33EditFieldLabel            matlab.ui.control.Label
        E64EditField                 matlab.ui.control.NumericEditField
        E64EditFieldLabel            matlab.ui.control.Label
        E63EditField                 matlab.ui.control.NumericEditField
        E63EditFieldLabel            matlab.ui.control.Label
        E62EditField                 matlab.ui.control.NumericEditField
        E62EditFieldLabel            matlab.ui.control.Label
        E61EditField                 matlab.ui.control.NumericEditField
        E61EditFieldLabel            matlab.ui.control.Label
        E60EditField                 matlab.ui.control.NumericEditField
        E60EditFieldLabel            matlab.ui.control.Label
        E59EditField                 matlab.ui.control.NumericEditField
        E59EditFieldLabel            matlab.ui.control.Label
        E58EditField                 matlab.ui.control.NumericEditField
        E58EditFieldLabel            matlab.ui.control.Label
        E25EditField                 matlab.ui.control.NumericEditField
        E25EditFieldLabel            matlab.ui.control.Label
        E24EditField                 matlab.ui.control.NumericEditField
        E24EditFieldLabel            matlab.ui.control.Label
        E23EditField                 matlab.ui.control.NumericEditField
        E23EditFieldLabel            matlab.ui.control.Label
        E22EditField                 matlab.ui.control.NumericEditField
        E22EditFieldLabel            matlab.ui.control.Label
        E21EditField                 matlab.ui.control.NumericEditField
        E21EditFieldLabel            matlab.ui.control.Label
        E20EditField                 matlab.ui.control.NumericEditField
        E20EditFieldLabel            matlab.ui.control.Label
        E19EditField                 matlab.ui.control.NumericEditField
        E19EditFieldLabel            matlab.ui.control.Label
        E18EditField                 matlab.ui.control.NumericEditField
        E18EditFieldLabel            matlab.ui.control.Label
        E17EditField                 matlab.ui.control.NumericEditField
        E17EditFieldLabel            matlab.ui.control.Label
        E16EditField                 matlab.ui.control.NumericEditField
        E16EditFieldLabel            matlab.ui.control.Label
        E15EditField                 matlab.ui.control.NumericEditField
        E15EditFieldLabel            matlab.ui.control.Label
        E14EditField                 matlab.ui.control.NumericEditField
        E14EditFieldLabel            matlab.ui.control.Label
        E13EditField                 matlab.ui.control.NumericEditField
        E13EditFieldLabel            matlab.ui.control.Label
        E12EditField                 matlab.ui.control.NumericEditField
        E12EditFieldLabel            matlab.ui.control.Label
        E11EditField                 matlab.ui.control.NumericEditField
        E11EditFieldLabel            matlab.ui.control.Label
        E10EditField                 matlab.ui.control.NumericEditField
        E10EditFieldLabel            matlab.ui.control.Label
        E9EditField                  matlab.ui.control.NumericEditField
        E9EditFieldLabel             matlab.ui.control.Label
        E8EditField                  matlab.ui.control.NumericEditField
        E8EditFieldLabel             matlab.ui.control.Label
        E7EditField                  matlab.ui.control.NumericEditField
        E7EditFieldLabel             matlab.ui.control.Label
        E6EditField                  matlab.ui.control.NumericEditField
        E6EditFieldLabel             matlab.ui.control.Label
        E5EditField                  matlab.ui.control.NumericEditField
        E5EditFieldLabel             matlab.ui.control.Label
        E4EditField                  matlab.ui.control.NumericEditField
        E4EditFieldLabel             matlab.ui.control.Label
        E3EditField                  matlab.ui.control.NumericEditField
        E3EditFieldLabel             matlab.ui.control.Label
        E2EditField                  matlab.ui.control.NumericEditField
        E2EditFieldLabel             matlab.ui.control.Label
        E1EditField                  matlab.ui.control.NumericEditField
        E1EditFieldLabel             matlab.ui.control.Label
        E32EditField                 matlab.ui.control.NumericEditField
        E32EditFieldLabel            matlab.ui.control.Label
        E31EditField                 matlab.ui.control.NumericEditField
        E31EditFieldLabel            matlab.ui.control.Label
        E30EditField                 matlab.ui.control.NumericEditField
        E30EditFieldLabel            matlab.ui.control.Label
        E29EditField                 matlab.ui.control.NumericEditField
        E29EditFieldLabel            matlab.ui.control.Label
        E28EditField                 matlab.ui.control.NumericEditField
        E28EditFieldLabel            matlab.ui.control.Label
        E27EditField                 matlab.ui.control.NumericEditField
        E27EditFieldLabel            matlab.ui.control.Label
        E26EditField                 matlab.ui.control.NumericEditField
        E26EditFieldLabel            matlab.ui.control.Label
        ArbolDescomposicionGraf      matlab.ui.control.UIAxes
        MatrizdeConfusionTab         matlab.ui.container.Tab
        PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2  matlab.ui.control.Label
        Accuracy9936Label            matlab.ui.control.Label
        MatrizdeConfusinLabel        matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function setDefaults(app)
            app.FrecuenciaFsEditField.Value = num2str(44100);        % Frec muestreo 
            app.rbitsmuestrasEditField.Value = num2str(16);          % 16 bits por muestra
            app.decanalesEditField.Value = num2str(1);               % monocanal
            app.TcsegundosEditField.Value = num2str(2);              % tiempo grabacion en segundos 
            app.GrabacionEmpezo.Text = '-'; 
            app.LapalabraesEditField.Editable = false;

            img = imread('arbol.png');
            imshow(img, 'Parent', app.ArbolDescomposicionGraf);
            axis(app.ArbolDescomposicionGraf, 'image');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaults(app);
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
             
            % global Fs x t r  
            
            t = str2double(app.TcsegundosEditField.Value);
            Fs= str2double(app.FrecuenciaFsEditField.Value);
            nc= str2double(app.decanalesEditField.Value);
            r= str2double(app.rbitsmuestrasEditField.Value);
            

            recObj = audiorecorder(Fs,r,nc);

            % Iniciar la grabación
            app.LapalabraesEditField.Value = ' ';           % Limpiar el texto anterior
            app.GrabacionEmpezo.Text = 'Grabación empezó';
            recordblocking(recObj, t);                      % Grabar durante 2 segundos
            app.GrabacionEmpezo.Text = 'Grabación finalizada';
            x = getaudiodata(recObj,'double');
            

            x = x / max(abs(x));                            % Normalización del audio

            audiowrite('audio.wav',x,Fs)                    % Guardar el archivo en formato WAV
            [x,Fs] = audioread('audio.wav','double');       % Leer el archivo guardado

            
            v_t = (0:length(x)-1)' * 1/Fs;                  % Eje de tiempo en segundos

            x_f = fft(x);                                   % FFT de la señal de audio
            x_f = x_f(1:length(x_f)/2+1);                   % Tomar solo la mitad positiva del espectro
            
            f = (0:length(x_f)-1)' * Fs/(length(x_f)-1);    % Eje de frecuencia en Hz

            plot(app.GraficaTiempo,v_t,x);
            plot(app.GraficaFrecuencia,f,abs(x_f));

%---------------------------------------------------------------------------------------------

            % Generación de filtros de la familia wavelet Daubechies de orden 25
            [h0, h1, f0, f1] = wfilters('db25'); 
            
            % Aplicar la función dwwt
            [x11, x12] = dwwt(x, h0, h1); % NIVEL 1
            
            [x21, x22] = dwwt(x11,h0,h1); % NIVEL 2
            [x23, x24] = dwwt(x12,h0,h1); % NIVEL 2
    
            [x31, x32] = dwwt(x21,h0,h1); % NIVEL 3
            [x33, x34] = dwwt(x22,h0,h1); % NIVEL 3
            [x35, x36] = dwwt(x23,h0,h1); % NIVEL 3
            [x37, x38] = dwwt(x24,h0,h1); % NIVEL 3
    
            [x41, x42] = dwwt(x31,h0,h1); % NIVEL 4
            [x43, x44] = dwwt(x32,h0,h1); % NIVEL 4
            [x45, x46] = dwwt(x33,h0,h1); % NIVEL 4
            [x47, x48] = dwwt(x34,h0,h1); % NIVEL 4
            [x49, x410] = dwwt(x35,h0,h1); % NIVEL 4
            [x411, x412] = dwwt(x36,h0,h1); % NIVEL 4
            [x413, x414] = dwwt(x37,h0,h1); % NIVEL 4
            [x415, x416] = dwwt(x38,h0,h1); % NIVEL 4
    
            [x51, x52] = dwwt(x41,h0,h1); % NIVEL 5
            [x53, x54] = dwwt(x42,h0,h1); % NIVEL 5
            [x55, x56] = dwwt(x43,h0,h1); % NIVEL 5
            [x57, x58] = dwwt(x44,h0,h1); % NIVEL 5
            [x59, x510] = dwwt(x45,h0,h1); % NIVEL 5
            [x511, x512] = dwwt(x46,h0,h1); % NIVEL 5
            [x513, x514] = dwwt(x47,h0,h1); % NIVEL 5
            [x515, x516] = dwwt(x48,h0,h1); % NIVEL 5
            [x517, x518] = dwwt(x49,h0,h1); % NIVEL 5
            [x519, x520] = dwwt(x410,h0,h1); % NIVEL 5
            [x521, x522] = dwwt(x411,h0,h1); % NIVEL 5
            [x523, x524] = dwwt(x412,h0,h1); % NIVEL 5
            [x525, x526] = dwwt(x413,h0,h1); % NIVEL 5
            [x527, x528] = dwwt(x414,h0,h1); % NIVEL 5
            [x529, x530] = dwwt(x415,h0,h1); % NIVEL 5
            [x531, x532] = dwwt(x416,h0,h1); % NIVEL 5
            
            [x61, x62] = dwwt(x51,h0,h1); % NIVEL 6
            [x63, x64] = dwwt(x52,h0,h1); % NIVEL 6
            [x65, x66] = dwwt(x53,h0,h1); % NIVEL 6
            [x67, x68] = dwwt(x54,h0,h1); % NIVEL 6
            [x69, x610] = dwwt(x55,h0,h1); % NIVEL 6
            [x611, x612] = dwwt(x56,h0,h1); % NIVEL 6
            [x613, x614] = dwwt(x57,h0,h1); % NIVEL 6
            [x615, x616] = dwwt(x58,h0,h1); % NIVEL 6
            [x617, x618] = dwwt(x59,h0,h1); % NIVEL 6
            [x619, x620] = dwwt(x510,h0,h1); % NIVEL 6
            [x621, x622] = dwwt(x511,h0,h1); % NIVEL 6
            [x623, x624] = dwwt(x512,h0,h1); % NIVEL 6
            [x625, x626] = dwwt(x513,h0,h1); % NIVEL 6
            [x627, x628] = dwwt(x514,h0,h1); % NIVEL 6
            [x629, x630] = dwwt(x515,h0,h1); % NIVEL 6
            [x631, x632] = dwwt(x516,h0,h1); % NIVEL 6
            [x633, x634] = dwwt(x517,h0,h1); % NIVEL 6
            [x635, x636] = dwwt(x518,h0,h1); % NIVEL 6
            [x637, x638] = dwwt(x519,h0,h1); % NIVEL 6
            [x639, x640] = dwwt(x520,h0,h1); % NIVEL 6
            [x641, x642] = dwwt(x521,h0,h1); % NIVEL 6
            [x643, x644] = dwwt(x522,h0,h1); % NIVEL 6
            [x645, x646] = dwwt(x523,h0,h1); % NIVEL 6
            [x647, x648] = dwwt(x524,h0,h1); % NIVEL 6
            [x649, x650] = dwwt(x525,h0,h1); % NIVEL 6
            [x651, x652] = dwwt(x526,h0,h1); % NIVEL 6
            [x653, x654] = dwwt(x527,h0,h1); % NIVEL 6
            [x655, x656] = dwwt(x528,h0,h1); % NIVEL 6
            [x657, x658] = dwwt(x529,h0,h1); % NIVEL 6
            [x659, x660] = dwwt(x530,h0,h1); % NIVEL 6
            [x661, x662] = dwwt(x531,h0,h1); % NIVEL 6
            [x663, x664] = dwwt(x532,h0,h1); % NIVEL 6    


            % Calcular la energía de los coeficientes
            E1 = sum(x61.^2); %ENERGIA 1
            E2 = sum(x62.^2); %ENERGIA 2
            E3 = sum(x63.^2); %ENERGIA 3
            E4 = sum(x64.^2); %ENERGIA 4
            E5 = sum(x65.^2); %ENERGIA 5
            E6 = sum(x66.^2); %ENERGIA 6
            E7 = sum(x67.^2); %ENERGIA 7
            E8 = sum(x68.^2); %ENERGIA 8
            E9 = sum(x69.^2); %ENERGIA 9
            E10 = sum(x610.^2); %ENERGIA 10
            E11 = sum(x611.^2); %ENERGIA 11
            E12 = sum(x612.^2); %ENERGIA 12
            E13 = sum(x613.^2); %ENERGIA 13
            E14 = sum(x614.^2); %ENERGIA 14
            E15 = sum(x615.^2); %ENERGIA 15
            E16 = sum(x616.^2); %ENERGIA 16
            E17 = sum(x617.^2); %ENERGIA 17
            E18 = sum(x618.^2); %ENERGIA 18
            E19 = sum(x619.^2); %ENERGIA 19
            E20 = sum(x620.^2); %ENERGIA 20
            E21 = sum(x621.^2); %ENERGIA 21
            E22 = sum(x622.^2); %ENERGIA 22
            E23 = sum(x623.^2); %ENERGIA 23
            E24 = sum(x624.^2); %ENERGIA 24
            E25 = sum(x625.^2); %ENERGIA 25
            E26 = sum(x626.^2); %ENERGIA 26
            E27 = sum(x627.^2); %ENERGIA 27
            E28 = sum(x628.^2); %ENERGIA 28
            E29 = sum(x629.^2); %ENERGIA 29
            E30 = sum(x630.^2); %ENERGIA 30
            E31 = sum(x631.^2); %ENERGIA 31
            E32 = sum(x632.^2); %ENERGIA 32
            E33 = sum(x633.^2); %ENERGIA 33
            E34 = sum(x634.^2); %ENERGIA 34
            E35 = sum(x635.^2); %ENERGIA 35
            E36 = sum(x636.^2); %ENERGIA 36
            E37 = sum(x637.^2); %ENERGIA 37
            E38 = sum(x638.^2); %ENERGIA 38
            E39 = sum(x639.^2); %ENERGIA 39
            E40 = sum(x640.^2); %ENERGIA 40
            E41 = sum(x641.^2); %ENERGIA 41
            E42 = sum(x642.^2); %ENERGIA 42
            E43 = sum(x643.^2); %ENERGIA 43
            E44 = sum(x644.^2); %ENERGIA 44
            E45 = sum(x645.^2); %ENERGIA 45
            E46 = sum(x646.^2); %ENERGIA 46
            E47 = sum(x647.^2); %ENERGIA 47
            E48 = sum(x648.^2); %ENERGIA 48
            E49 = sum(x649.^2); %ENERGIA 49
            E50 = sum(x650.^2); %ENERGIA 50
            E51 = sum(x651.^2); %ENERGIA 51
            E52 = sum(x652.^2); %ENERGIA 52
            E53 = sum(x653.^2); %ENERGIA 53
            E54 = sum(x654.^2); %ENERGIA 54
            E55 = sum(x655.^2); %ENERGIA 55
            E56 = sum(x656.^2); %ENERGIA 56
            E57 = sum(x657.^2); %ENERGIA 57
            E58 = sum(x658.^2); %ENERGIA 58
            E59 = sum(x659.^2); %ENERGIA 59
            E60 = sum(x660.^2); %ENERGIA 60
            E61 = sum(x661.^2); %ENERGIA 61
            E62 = sum(x662.^2); %ENERGIA 62
            E63 = sum(x663.^2); %ENERGIA 63
            E64 = sum(x664.^2); %ENERGIA 64

            E_Palabras = [E1 E2 E3 E4 E5 E6 E7 E8 E9 10 E11 E12 E13 E14 E15 E16 E17 E18 E19 E20 E21 E22 E23 E24 E25 E26 E27 E28 E29 E30 E31 E32 E33 E34 E35 E36 E37 E38 E39 E40 E41 E42 E43 E44 E45 E46 E47 E48 E49 E50 E51 E52 E53 E54 E55 E56 E57 E58 E59 E60 E61 E62 E63 E64];
            
            %% Usando Support Vector Machine para detectar vocal

            load('TablaModelo.mat', 'Modelo');
            % Convertir el vector de características en una tabla para la predicción
            Tabla_Prediccion = array2table(E_Palabras, 'VariableNames', Modelo.PredictorNames);
            
            % Realizar la predicción
            prediccion = predict(Modelo, Tabla_Prediccion);

            app.LapalabraesEditField.Value = char(prediccion);

            app.E1EditField.Value = E1; app.E1EditField.Editable = false;
            app.E2EditField.Value = E2; app.E2EditField.Editable = false;
            app.E3EditField.Value = E3; app.E3EditField.Editable = false;
            app.E4EditField.Value = E4; app.E4EditField.Editable = false;
            app.E5EditField.Value = E5; app.E5EditField.Editable = false;
            app.E6EditField.Value = E6; app.E6EditField.Editable = false;
            app.E7EditField.Value = E7; app.E7EditField.Editable = false;
            app.E8EditField.Value = E8; app.E8EditField.Editable = false;
            app.E9EditField.Value = E9; app.E9EditField.Editable = false;
            app.E10EditField.Value = E10; app.E10EditField.Editable = false;
            app.E11EditField.Value = E11; app.E11EditField.Editable = false;
            app.E12EditField.Value = E12; app.E12EditField.Editable = false;
            app.E13EditField.Value = E13; app.E13EditField.Editable = false;
            app.E14EditField.Value = E14; app.E14EditField.Editable = false;
            app.E15EditField.Value = E15; app.E15EditField.Editable = false;
            app.E16EditField.Value = E16; app.E16EditField.Editable = false;
            app.E17EditField.Value = E17; app.E17EditField.Editable = false;
            app.E18EditField.Value = E18; app.E18EditField.Editable = false;
            app.E19EditField.Value = E19; app.E19EditField.Editable = false;
            app.E20EditField.Value = E20; app.E20EditField.Editable = false;
            app.E21EditField.Value = E21; app.E21EditField.Editable = false;
            app.E22EditField.Value = E22; app.E22EditField.Editable = false;
            app.E23EditField.Value = E23; app.E23EditField.Editable = false;
            app.E24EditField.Value = E24; app.E24EditField.Editable = false;
            app.E25EditField.Value = E25; app.E25EditField.Editable = false;
            app.E26EditField.Value = E26; app.E26EditField.Editable = false;
            app.E27EditField.Value = E27; app.E27EditField.Editable = false;
            app.E28EditField.Value = E28; app.E28EditField.Editable = false;
            app.E29EditField.Value = E29; app.E29EditField.Editable = false;
            app.E30EditField.Value = E30; app.E30EditField.Editable = false;
            app.E31EditField.Value = E31; app.E31EditField.Editable = false;
            app.E32EditField.Value = E32; app.E32EditField.Editable = false;
            app.E33EditField.Value = E33; app.E33EditField.Editable = false;
            app.E34EditField.Value = E34; app.E34EditField.Editable = false;
            app.E35EditField.Value = E35; app.E35EditField.Editable = false;
            app.E36EditField.Value = E36; app.E36EditField.Editable = false;
            app.E37EditField.Value = E37; app.E37EditField.Editable = false;
            app.E38EditField.Value = E38; app.E38EditField.Editable = false;
            app.E39EditField.Value = E39; app.E39EditField.Editable = false;
            app.E40EditField.Value = E40; app.E40EditField.Editable = false;
            app.E41EditField.Value = E41; app.E41EditField.Editable = false;
            app.E42EditField.Value = E42; app.E42EditField.Editable = false;
            app.E43EditField.Value = E43; app.E43EditField.Editable = false;
            app.E44EditField.Value = E44; app.E44EditField.Editable = false;
            app.E45EditField.Value = E45; app.E45EditField.Editable = false;
            app.E46EditField.Value = E46; app.E46EditField.Editable = false;
            app.E47EditField.Value = E47; app.E47EditField.Editable = false;
            app.E48EditField.Value = E48; app.E48EditField.Editable = false;
            app.E49EditField.Value = E49; app.E49EditField.Editable = false;
            app.E50EditField.Value = E50; app.E50EditField.Editable = false;
            app.E51EditField.Value = E51; app.E51EditField.Editable = false;
            app.E52EditField.Value = E52; app.E52EditField.Editable = false;
            app.E53EditField.Value = E53; app.E53EditField.Editable = false;
            app.E54EditField.Value = E54; app.E54EditField.Editable = false;
            app.E55EditField.Value = E55; app.E55EditField.Editable = false;
            app.E56EditField.Value = E56; app.E56EditField.Editable = false;
            app.E57EditField.Value = E57; app.E57EditField.Editable = false;
            app.E58EditField.Value = E58; app.E58EditField.Editable = false;
            app.E59EditField.Value = E59; app.E59EditField.Editable = false;
            app.E60EditField.Value = E60; app.E60EditField.Editable = false;
            app.E61EditField.Value = E61; app.E61EditField.Editable = false;
            app.E62EditField.Value = E62; app.E62EditField.Editable = false;
            app.E63EditField.Value = E63; app.E63EditField.Editable = false;
            app.E64EditField.Value = E64; app.E64EditField.Editable = false;

        end

        % Button pushed function: VerArbolButton
        function VerArbolButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ArboldedescomposicionTab;
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            [x,Fs] = audioread('audio.wav');       % Leer el archivo guardado
            sound(x, Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfazprincipalTab = uitab(app.TabGroup);
            app.InterfazprincipalTab.Title = 'Interfaz principal';
            app.GraficaTiempo = uiaxes(app.InterfazprincipalTab);
            title(app.GraficaTiempo, 'Señal en el tiempo')
            xlabel(app.GraficaTiempo, 'Tiempo (s)')
            ylabel(app.GraficaTiempo, 'Amplitud')
            zlabel(app.GraficaTiempo, 'Z')
            app.GraficaFrecuencia = uiaxes(app.InterfazprincipalTab);
            title(app.GraficaFrecuencia, 'Señal en frecuencia')
            xlabel(app.GraficaFrecuencia, 'Frecuencia (Hz)')
            ylabel(app.GraficaFrecuencia, 'Amplitud')
            zlabel(app.GraficaFrecuencia, 'Z')
            app.FrecuenciaFsEditFieldLabel = uilabel(app.InterfazprincipalTab);
            app.FrecuenciaFsEditFieldLabel.Text = 'Frecuencia (Fs):';
            app.FrecuenciaFsEditField = uieditfield(app.InterfazprincipalTab, 'text');
            app.rbitsmuestrasEditFieldLabel = uilabel(app.InterfazprincipalTab);
            app.rbitsmuestrasEditFieldLabel.Text = 'r (bits/muestras):';
            app.rbitsmuestrasEditField = uieditfield(app.InterfazprincipalTab, 'text');
            app.decanalesEditFieldLabel = uilabel(app.InterfazprincipalTab);
            app.decanalesEditFieldLabel.Text = '# de canales:';
            app.decanalesEditField = uieditfield(app.InterfazprincipalTab, 'text');
            app.TcsegundosEditFieldLabel = uilabel(app.InterfazprincipalTab);
            app.TcsegundosEditFieldLabel.Text = 'Tc (segundos):';
            app.TcsegundosEditField = uieditfield(app.InterfazprincipalTab, 'text');
            app.GrabarButton = uibutton(app.InterfazprincipalTab, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.ReproducirButton = uibutton(app.InterfazprincipalTab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.VerArbolButton = uibutton(app.InterfazprincipalTab, 'push');
            app.VerArbolButton.ButtonPushedFcn = createCallbackFcn(app, @VerArbolButtonPushed, true);
            app.VerArbolButton.Text = 'Ver Arbol';
            app.LapalabraesEditFieldLabel = uilabel(app.InterfazprincipalTab);
            app.LapalabraesEditFieldLabel.Text = 'La palabra es:';
            app.LapalabraesEditField = uieditfield(app.InterfazprincipalTab, 'text');
            app.GrabacionEmpezo = uilabel(app.InterfazprincipalTab);
            app.GrabacionEmpezo.Text = '';
            app.Image3 = uiimage(app.InterfazprincipalTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'UPC_logo_transparente.png');
            app.PROCESAMIENTOAVANZADODESEALESEIMGENESTRABAJOPARCIALLabel = uilabel(app.InterfazprincipalTab);
            app.PROCESAMIENTOAVANZADODESEALESEIMGENESTRABAJOPARCIALLabel.Text = {'PROCESAMIENTO AVANZADO DE SEÑALES E IMÁGENES'; 'TRABAJO PARCIAL'};
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel = uilabel(app.InterfazprincipalTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán                                                               2024-02';
            app.ArboldedescomposicionTab = uitab(app.TabGroup);
            app.ArboldedescomposicionTab.Title = 'Arbol de descomposicion';
            app.ArbolDescomposicionGraf = uiaxes(app.ArboldedescomposicionTab);
            title(app.ArbolDescomposicionGraf, 'Árbol de descomposición de nivel 6')
            xlabel(app.ArbolDescomposicionGraf, ' ')
            zlabel(app.ArbolDescomposicionGraf, 'Z')
            app.E26EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E26EditFieldLabel.Text = 'E26';
            app.E26EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E27EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E27EditFieldLabel.Text = 'E27';
            app.E27EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E28EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E28EditFieldLabel.Text = 'E28';
            app.E28EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E29EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E29EditFieldLabel.Text = 'E29';
            app.E29EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E30EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E30EditFieldLabel.Text = 'E30';
            app.E30EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E31EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E31EditFieldLabel.Text = 'E31';
            app.E31EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E32EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E32EditFieldLabel.Text = 'E32';
            app.E32EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E1EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E1EditFieldLabel.Text = 'E1';
            app.E1EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E2EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E3EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E3EditFieldLabel.Text = 'E3';
            app.E3EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E4EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E4EditFieldLabel.Text = 'E4';
            app.E4EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E5EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E5EditFieldLabel.Text = 'E5';
            app.E5EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E6EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E6EditFieldLabel.Text = 'E6';
            app.E6EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E7EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E7EditFieldLabel.Text = 'E7';
            app.E7EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E8EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E8EditFieldLabel.Text = 'E8';
            app.E8EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E9EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E9EditFieldLabel.Text = 'E9';
            app.E9EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E10EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E10EditFieldLabel.Text = 'E10';
            app.E10EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E11EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E11EditFieldLabel.Text = 'E11';
            app.E11EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E12EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E12EditFieldLabel.Text = 'E12';
            app.E12EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E13EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E13EditFieldLabel.Text = 'E13';
            app.E13EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E14EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E14EditFieldLabel.Text = 'E14';
            app.E14EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E15EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E15EditFieldLabel.Text = 'E15';
            app.E15EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E16EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E16EditFieldLabel.Text = 'E16';
            app.E16EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E17EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E17EditFieldLabel.Text = 'E17';
            app.E17EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E18EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E18EditFieldLabel.Text = 'E18';
            app.E18EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E19EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E19EditFieldLabel.Text = 'E19';
            app.E19EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E20EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E20EditFieldLabel.Text = 'E20';
            app.E20EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E21EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E21EditFieldLabel.Text = 'E21';
            app.E21EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E22EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E22EditFieldLabel.Text = 'E22';
            app.E22EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E23EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E23EditFieldLabel.Text = 'E23';
            app.E23EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E24EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E24EditFieldLabel.Text = 'E24';
            app.E24EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E25EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E25EditFieldLabel.Text = 'E25';
            app.E25EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E58EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E58EditFieldLabel.Text = 'E58';
            app.E58EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E59EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E59EditFieldLabel.Text = 'E59';
            app.E59EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E60EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E60EditFieldLabel.Text = 'E60';
            app.E60EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E61EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E61EditFieldLabel.Text = 'E61';
            app.E61EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E62EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E62EditFieldLabel.Text = 'E62';
            app.E62EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E63EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E63EditFieldLabel.Text = 'E63';
            app.E63EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E64EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E64EditFieldLabel.Text = 'E64';
            app.E64EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E33EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E33EditFieldLabel.Text = 'E33';
            app.E33EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E34EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E34EditFieldLabel.Text = 'E34';
            app.E34EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E35EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E35EditFieldLabel.Text = 'E35';
            app.E35EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E36EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E36EditFieldLabel.Text = 'E36';
            app.E36EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E37EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E37EditFieldLabel.Text = 'E37';
            app.E37EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E38EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E38EditFieldLabel.Text = 'E38';
            app.E38EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E39EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E39EditFieldLabel.Text = 'E39';
            app.E39EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E40EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E40EditFieldLabel.Text = 'E40';
            app.E40EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E41EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E41EditFieldLabel.Text = 'E41';
            app.E41EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E42EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E42EditFieldLabel.Text = 'E42';
            app.E42EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E43Label = uilabel(app.ArboldedescomposicionTab);
            app.E43Label.Text = 'E43';
            app.E43EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E44EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E44EditFieldLabel.Text = 'E44';
            app.E44EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E45EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E45EditFieldLabel.Text = 'E45';
            app.E45EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E46EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E46EditFieldLabel.Text = 'E46';
            app.E46EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E47EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E47EditFieldLabel.Text = 'E47';
            app.E47EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E48EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E48EditFieldLabel.Text = 'E48';
            app.E48EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E49EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E49EditFieldLabel.Text = 'E49';
            app.E49EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E50EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E50EditFieldLabel.Text = 'E50';
            app.E50EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E51EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E51EditFieldLabel.Text = 'E51';
            app.E51EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E52EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E52EditFieldLabel.Text = 'E52';
            app.E52EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E53EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E53EditFieldLabel.Text = 'E53';
            app.E53EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E54EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E54EditFieldLabel.Text = 'E54';
            app.E54EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E55EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E55EditFieldLabel.Text = 'E55';
            app.E55EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E56EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E56EditFieldLabel.Text = 'E56';
            app.E56EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.E57EditFieldLabel = uilabel(app.ArboldedescomposicionTab);
            app.E57EditFieldLabel.Text = 'E57';
            app.E57EditField = uieditfield(app.ArboldedescomposicionTab, 'numeric');
            app.MatrizdeConfusionTab = uitab(app.TabGroup);
            app.MatrizdeConfusionTab.Title = 'Matriz de Confusion';
            app.Image2 = uiimage(app.MatrizdeConfusionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'confusion_matriz', 'matriz.jpg');
            app.MatrizdeConfusinLabel = uilabel(app.MatrizdeConfusionTab);
            app.MatrizdeConfusinLabel.Text = 'Matriz de Confusión:';
            app.Accuracy9936Label = uilabel(app.MatrizdeConfusionTab);
            app.Accuracy9936Label.Text = 'Accuracy: 99.36%';
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2 = uilabel(app.MatrizdeConfusionTab);
            app.PresentadoporJoannaCarrionKatherineCondorJenaCuevaLabel_2.Text = 'Presentado por: Joanna Carrión, Katherine Condor, Jean Cueva, Walther Retuerto, Anthony Santillán';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZ_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end